classdef gauge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        MeasuredDateTimeLabel       matlab.ui.control.Label
        RealTimeAngleDifferenceMeasurementLabel  matlab.ui.control.Label
        BACKButton                  matlab.ui.control.Button
        AngleDifferenceEditField    matlab.ui.control.NumericEditField
        AngleDifferenceLabel        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ResetButton                 matlab.ui.control.Button
        ReadButton                  matlab.ui.control.Button
        KneeFlexionAngleGauge       matlab.ui.control.SemicircularGauge
        KneeFlexionAngleGaugeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainpageAppHandle    % Define mainpageAppHandle property
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            try
            % Establish database connection
            conn = database('test1', 'root', '');

            % Set SQL query
            query = ['SELECT * ' ...
                     'FROM knee1_db.angle_diff'];

            % Execute query and fetch results
            data = fetch(conn, query);
            
            % Update UI components with fetched data
            if ~isempty(data)
                % Assuming you have a specific column in your data that you want to display in the gauge
                angle_value = data.X_Angle_Difference(end); % Assuming you want the first row's value
                app.KneeFlexionAngleGauge.Value = angle_value;
                app.AngleDifferenceEditField.Value = angle_value;
                app.Label.Text = data.Time_X_1(end);
            else
                errordlg('No data retrieved from the database.', 'Database Error', 'modal');
            end

            % Close database connection
            close(conn);
        catch ME
            % Handle errors
            errordlg(['Error: ' ME.message], 'Database Error', 'modal');
            if ~isempty(conn) && isopen(conn)
                close(conn);
            end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.KneeFlexionAngleGauge.Value=0.00;
            app.AngleDifferenceEditField.Value = 0.00;
            app.Label.Text = '-';
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            % Open the mainpage app
            app.mainpageAppHandle = mainpage;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KneeFlexionAngleGaugeLabel = uilabel(app.UIFigure);
            app.KneeFlexionAngleGaugeLabel.Text = 'Knee Flexion Angle';
            app.KneeFlexionAngleGauge = uigauge(app.UIFigure, 'semicircular');
            app.KneeFlexionAngleGauge.Limits = [0 150];
            app.KneeFlexionAngleGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.KneeFlexionAngleGauge.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140', '150', '160', '170', '180'};
            app.KneeFlexionAngleGauge.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100 102 104 106 108 110 112 114 116 118 120 122 124 126 128 130 132 134 136 138 140 142 144 146 148 150 152 154 156 158 160 162 164 166 168 170 172 174 176 178 180];
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Image 3.png');
            app.AngleDifferenceLabel = uilabel(app.UIFigure);
            app.AngleDifferenceLabel.Text = 'Angle Difference (°)';
            app.AngleDifferenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.RealTimeAngleDifferenceMeasurementLabel = uilabel(app.UIFigure);
            app.RealTimeAngleDifferenceMeasurementLabel.Text = 'Real Time Angle Difference Measurement';
            app.MeasuredDateTimeLabel = uilabel(app.UIFigure);
            app.MeasuredDateTimeLabel.Text = 'Measured Date & Time';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end