classdef mainpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Version10Label                  matlab.ui.control.Label
        UserManualButton                matlab.ui.control.Button
        MeasurementAnalysisButton       matlab.ui.control.Button
        DrKneeMovoLabel                 matlab.ui.control.Label
        MeasurementVisualisationButton  matlab.ui.control.Button
        RealTimeMeasurementButton       matlab.ui.control.Button
    end

    properties (Access = private)
        GaugeAppHandle  % Define GaugeAppHandle property
        RealtimeplotAppHandle
        AnalysisAppHandle
        UserManualAppHandle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RealTimeMeasurementButton
        function RealTimeMeasurementButtonPushed(app, event)
             % Open the gauge app
            app.GaugeAppHandle = gauge;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: MeasurementVisualisationButton
        function MeasurementVisualisationButtonPushed(app, event)
             % Open the gauge app
            app.RealtimeplotAppHandle = realtimeplot;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: MeasurementAnalysisButton
        function MeasurementAnalysisButtonPushed(app, event)
            % Open the gauge app
            app.AnalysisAppHandle = analysispage;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: UserManualButton
        function UserManualButtonPushed(app, event)
            % Open the gauge app
            app.UserManualAppHandle = usermanual;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealTimeMeasurementButton = uibutton(app.UIFigure, 'push');
            app.RealTimeMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @RealTimeMeasurementButtonPushed, true);
            app.RealTimeMeasurementButton.Icon = fullfile(pathToMLAPP, 'Image 1.png');
            app.RealTimeMeasurementButton.Text = 'Real Time Measurement';
            app.MeasurementVisualisationButton = uibutton(app.UIFigure, 'push');
            app.MeasurementVisualisationButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementVisualisationButtonPushed, true);
            app.MeasurementVisualisationButton.Icon = fullfile(pathToMLAPP, 'Visualisation.png');
            app.MeasurementVisualisationButton.Text = 'Measurement Visualisation';
            app.DrKneeMovoLabel = uilabel(app.UIFigure);
            app.DrKneeMovoLabel.Text = 'Dr.Knee Movo';
            app.MeasurementAnalysisButton = uibutton(app.UIFigure, 'push');
            app.MeasurementAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementAnalysisButtonPushed, true);
            app.MeasurementAnalysisButton.Icon = fullfile(pathToMLAPP, 'Image 2.png');
            app.MeasurementAnalysisButton.Text = 'Measurement Analysis';
            app.UserManualButton = uibutton(app.UIFigure, 'push');
            app.UserManualButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualButtonPushed, true);
            app.UserManualButton.Icon = fullfile(pathToMLAPP, 'Image 4.png');
            app.UserManualButton.Text = 'User Manual';
            app.Version10Label = uilabel(app.UIFigure);
            app.Version10Label.Text = 'Version 1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainpage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end