classdef realtimeplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AvailableMeasurementPointsinTotalEditField  matlab.ui.control.EditField
        AvailableMeasurementPointsinTotalEditFieldLabel  matlab.ui.control.Label
        AvailablemeasurementpointsinthetimerangeEditField  matlab.ui.control.NumericEditField
        AvailablemeasurementpointsinthetimerangeEditFieldLabel  matlab.ui.control.Label
        ActualendTimeEditField         matlab.ui.control.EditField
        ActualendTimeEditFieldLabel    matlab.ui.control.Label
        ActualstartTimeEditField       matlab.ui.control.EditField
        ActualstartTimeEditFieldLabel  matlab.ui.control.Label
        EnteranendTimeEditField        matlab.ui.control.EditField
        EnteranendTimeEditFieldLabel   matlab.ui.control.Label
        EnterastartTimeEditField       matlab.ui.control.EditField
        EnterastartTimeEditFieldLabel  matlab.ui.control.Label
        BACKButton                     matlab.ui.control.Button
        AngleDifferenceVisualisationLabel  matlab.ui.control.Label
        VisualiseButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
         mainpageAppHandle    % Define mainpageAppHandle property
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VisualiseButton
        function PlotButtonPushed(app, event)
            %% Make connection to database
            conn = database('test1','root','');
            
            % Set query to execute on the database
            query = 'SELECT * FROM knee1_db.angle_diff';
            
            % Execute query and fetch results
            data = fetch(conn,query);
            
            X_ID = data.ID_3;
            X_Angle = data.X_Angle_Difference;
            X_Time = data.Time_X_1;

            %Retrieve start and end times entered by the user
            start_time =app.EnterastartTimeEditField.Value;
            end_time =app.EnteranendTimeEditField.Value;
            
             try
                % Convert user input times to datetime format
                start_time_dt = datetime(start_time, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                end_time_dt = datetime(end_time, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                
                if(end_time_dt<=start_time_dt)
                    f = errordlg('Please enter a valid Start and End time range.','Date Range Error');
                else
                    % Find indices corresponding to the chosen start and end times
                    start_index = find(X_Time >= start_time_dt, 1);
                    end_index = find(X_Time <= end_time_dt, 1, 'last');
            
                    if isempty(start_index:end_index)
                        f = errordlg('There is no data within the range.','No Data in Range');
                    else
                        xlim(app.UIAxes, [X_ID(start_index), X_ID(end_index)]);
      
                        % Plot the data
                        line(app.UIAxes, X_ID, X_Angle,'LineWidth', 1);

                        % Customize the plot
                        ylim(app.UIAxes, [0 150]);
                        yticks(app.UIAxes, 0:10:150);

                        app.ActualstartTimeEditField.Value = string(X_Time(start_index));
                        app.ActualendTimeEditField.Value = string(X_Time(end_index));
                        app.AvailablemeasurementpointsinthetimerangeEditField.Value = X_ID(end_index)-X_ID(start_index);
                        
                        % Close database connection
                        close(conn);
                    end
                end
            catch
                f = errordlg('Please check the start and end datetime format.','Datetime format Error'); 
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            % Open the mainpage app
            app.mainpageAppHandle = mainpage;
    
            % Hide the mainpage app's UI figure
            app.UIFigure.Visible = 'off';
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % Making connection to database
            conn = database('test1', 'root', '');

            % MYSQL query to retrive all data from database
            query = ['SELECT * ' ...
                'FROM knee1_db.angle_diff'];

            % Execute query and fetch results
            data = fetch(conn, query);
            
            %Applying a placeholder for start time and end time editfield
            app.EnterastartTimeEditField.Placeholder = 'YYYY-MM-DD HH:MM:SS';
            app.EnteranendTimeEditField.Placeholder = 'YYYY-MM-DD HH:MM:SS';

            %Decalring variable for ID
            X_ID_1 = data.ID_3;
            app.AvailableMeasurementPointsinTotalEditField.Value = num2str(X_ID_1(end), '%d');

            % Close connection to database
            close(conn);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angle Differnce over measurement points')
            xlabel(app.UIAxes, 'Measurement Points')
            ylabel(app.UIAxes, 'Knee Angle Difference (degrees)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 150];
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'; '110'; '120'; '130'; '140'; '150'};
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.AngleDifferenceVisualisationLabel = uilabel(app.UIFigure);
            app.AngleDifferenceVisualisationLabel.Text = 'Angle Difference Visualisation';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.EnterastartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EnterastartTimeEditFieldLabel.Text = 'Enter a start Time';
            app.EnterastartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.EnteranendTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EnteranendTimeEditFieldLabel.Text = 'Enter an end Time';
            app.EnteranendTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ActualstartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ActualstartTimeEditFieldLabel.Text = 'Actual start Time';
            app.ActualstartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ActualstartTimeEditField.Editable = 'off';
            app.ActualendTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ActualendTimeEditFieldLabel.Text = 'Actual end Time';
            app.ActualendTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ActualendTimeEditField.Editable = 'off';
            app.AvailablemeasurementpointsinthetimerangeEditFieldLabel = uilabel(app.UIFigure);
            app.AvailablemeasurementpointsinthetimerangeEditFieldLabel.Text = 'Available measurement points in the time range';
            app.AvailablemeasurementpointsinthetimerangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AvailablemeasurementpointsinthetimerangeEditField.Editable = 'off';
            app.AvailableMeasurementPointsinTotalEditFieldLabel = uilabel(app.UIFigure);
            app.AvailableMeasurementPointsinTotalEditFieldLabel.Text = 'Available Measurement Points in Total';
            app.AvailableMeasurementPointsinTotalEditField = uieditfield(app.UIFigure, 'text');
            app.AvailableMeasurementPointsinTotalEditField.InputType = 'digits';
            app.AvailableMeasurementPointsinTotalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realtimeplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end